classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        HVTNBIMINHNHTLPDTV_CLC1MSSV21207070Label  matlab.ui.control.Label
        bai32                     matlab.ui.control.Button
        bai43                     matlab.ui.control.Button
        bai52                     matlab.ui.control.Button
        bai65                     matlab.ui.control.Button
        Nghiem                    matlab.ui.control.Button
        PTrinh                    matlab.ui.control.EditField
        PhanliA                   matlab.ui.control.NumericEditField
        PhanliB                   matlab.ui.control.NumericEditField
        Saiso                     matlab.ui.control.NumericEditField
        PtrinhLap                 matlab.ui.control.EditField
        PhuongPhap0               matlab.ui.control.DropDown
        SoLanLap                  matlab.ui.control.NumericEditField
        KtquEditFieldLabel        matlab.ui.control.Label
        Ketqua                    matlab.ui.control.EditField
        Gioihan                   matlab.ui.control.Label
        NhpdliuLabel              matlab.ui.control.Label
        Giatrinoisuy              matlab.ui.control.NumericEditField
        GatrcnnisuyLabel          matlab.ui.control.Label
        SlnlpLabel                matlab.ui.control.Label
        Ketquadathuc              matlab.ui.control.EditField
        KqNSLb                    matlab.ui.control.Label
        saisolb                   matlab.ui.control.Label
        Buoch                     matlab.ui.control.NumericEditField
        Buochlb                   matlab.ui.control.Label
        Sodoanchia                matlab.ui.control.EditField
        Sodoanchialb              matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        BOCOGIAKLabel             matlab.ui.control.Label
        MNPHNGPHPTNHVMATLABLabel  matlab.ui.control.Label
        PhngtrnhLabel             matlab.ui.control.Label
        PhngphpLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Nghiem
        function NghiemButtonPushed(app, event)
switch app.PhuongPhap0.Value
    case 'Dây Cung'
        cla(app.UIAxes, "reset");
        fx = app.PTrinh.Value;
        a = app.PhanliA.Value;
        b = app.PhanliB.Value;
        saiso = app.Saiso.Value;
        [ketqua, solanlap] = PPdaycung(fx, a, b, saiso);
        app.Ketqua.Value = num2str(ketqua);
        app.SoLanLap.Value = double(solanlap);
        x = a:0.01:b;
        y = arrayfun(@(x) eval(fx), x);
        plot(app.UIAxes, x, y);
    case 'Newton'
        cla(app.UIAxes, "reset");
        fx = app.PTrinh.Value;
        a = app.PhanliA.Value;
        b = app.PhanliB.Value;
        saiso = app.Saiso.Value;
        [ketqua, solanlap] = PPNewton(fx, a, b, saiso);
        app.Ketqua.Value = num2str(ketqua);
        app.SoLanLap.Value = double(solanlap);
        x = a:0.01:b;
        y = arrayfun(@(x) eval(fx), x);
        plot(app.UIAxes, x, y);
    case 'Lặp'
        cla(app.UIAxes, "reset");
        fx = app.PTrinh.Value;
        gx = app.PtrinhLap.Value;
        a = app.PhanliA.Value;
        b = app.PhanliB.Value;
        saiso = app.Saiso.Value;
        [ketqua, solanlap] = PPLapDon(fx, gx, a, b, saiso);
        app.Ketqua.Value = num2str(ketqua);
        app.SoLanLap.Value = double(solanlap);
        x = a:0.01:b;
        y = arrayfun(@(x) eval(gx), x);
        z = x;
        plot(app.UIAxes, x, y, 'b--', x, z, 'r-');
    case 'Phương pháp lặp'
        cla(app.UIAxes, "reset");
        fx = str2num(app.PTrinh.Value);
        gx = str2num(app.PtrinhLap.Value);
        saiso = app.Saiso.Value;
        x = GiaiHePTLapDon(fx, gx, saiso, 100);
        app.Ketqua.Value = string(x);


case 'NS Newton'
    cla(app.UIAxes, "reset"); 
    xa = str2num(app.PTrinh.Value);
    ya = 3.^xa;
    x = app.Giatrinoisuy.Value;
    result = NS_NEWTON(xa, ya, x);
    app.Ketqua.Value = num2str(result);
    xPlot = linspace(min(xa), max(xa), 100);
    yPlot = NS_NEWTON(xa, ya, xPlot);
    plot(app.UIAxes, xPlot, yPlot);
    hold(app.UIAxes, 'on');
    scatter(app.UIAxes, xa, ya, 'filled');
    hold(app.UIAxes, 'off');
    p = polyfit(xa, ya, length(xa) - 1);
    app.Ketquadathuc.Value = poly2str(p, 'x');

    
case 'Tich Phan Hinh Thang'
  
    fx = @(x) (3.5 * x.^2 + 5.1 * x - 4.8) ./ (x - 0.4);

    a = app.PhanliA.Value;
    b = app.PhanliB.Value;
    epsilon = app.Saiso.Value;
    [approximation, n] = TichPhanHinhThang(fx, a, b, epsilon);
    app.Ketqua.Value = num2str(approximation);
    app.Sodoanchia.Value = num2str(n);
    xPlot = linspace(a, b, 100);
    yPlot = fx(xPlot);
    plot(app.UIAxes, xPlot, yPlot);
    hold(app.UIAxes, 'on');

    xTrap = linspace(a, b, n+1);
    yTrap = fx(xTrap);
for i = 1:n
    xSection = [xTrap(i), xTrap(i+1), xTrap(i+1), xTrap(i), xTrap(i)];
    ySection = [0, 0, yTrap(i+1), yTrap(i), 0];
    plot(app.UIAxes, xSection, ySection, 'r');
end
hold(app.UIAxes, 'off');


end

        end

        % Button pushed function: bai32
        function bai32ButtonPushed(app, event)
    app.PhanliA.Visible = 'on';
    app.PhanliB.Visible = 'on';
    app.Gioihan.Visible = 'on';
    app.SoLanLap.Visible = 'on';
    app.Giatrinoisuy.Visible = 'off';
     app.GatrcnnisuyLabel.Visible = 'off';
      app.SlnlpLabel.Visible = 'on';
      app.KqNSLb.Visible = 'off';
       app.Buoch.Visible = 'off';
           app.Buochlb.Visible = 'off';
      app.Ketquadathuc.Visible = 'off';
    app.PhuongPhap0.Items = {'Dây Cung','Lặp','Newton'};
                    app.Saiso.Visible = 'on';
                app.saisolb.Visible = 'on';
                                app.Saiso.Visible = 'on';
                app.saisolb.Visible = 'on';
                 app.Sodoanchia.Visible = 'off';
                 app.Sodoanchialb.Visible = 'off';

    app.PTrinh.Value = 'x^4 + 3*x - 20';
    app.Saiso.Value = 0.01;
    app.PhanliA.Value = 1;
    app.PhanliB.Value = 2;
    app.PtrinhLap.Value = '(20-3*x)^(1/4)';
        end

        % Button pushed function: bai43
        function bai43ButtonPushed(app, event)

    % Chinh sua giao dien
    app.PhanliA.Visible = 'off';
    app.PhanliB.Visible = 'off';
    app.Gioihan.Visible = 'off';
    app.SoLanLap.Visible = 'off';
    app.Ketquadathuc.Visible = 'off';
    app.Giatrinoisuy.Visible = 'off';
    app.SlnlpLabel.Visible = 'off';
    app.GatrcnnisuyLabel.Visible = 'off';
        app.Buoch.Visible = 'off';
           app.Buochlb.Visible = 'off';
      app.KqNSLb.Visible = 'off';
                       app.Sodoanchia.Visible = 'off';
                 app.Sodoanchialb.Visible = 'off';
    app.PhuongPhap0.Items = {'Phương pháp lặp',' '};

    % Ma trận A
    app.PTrinh.Value = '[2, 3, -4, 1; 1, -2, -5, 1; 5, -3, 1, -4; 10, 2, -1, 2]';

    % Vector B
    app.PtrinhLap.Value = '[3; 2; 1; -4]';
    app.Saiso.Value = 0.001;


        end

        % Button pushed function: bai52
        function bai52ButtonPushed(app, event)
            app.PhuongPhap0.Items = {'NS Newton',' '};
            app.KqNSLb.Visible = 'on';
                app.GatrcnnisuyLabel.Visible = 'on';
                app.Saiso.Visible = 'off';
                app.saisolb.Visible = 'off';
                app.GatrcnnisuyLabel.Visible = 'on';
                    app.Giatrinoisuy.Visible = 'on';
                        app.Ketquadathuc.Visible = 'on';
                           app.SlnlpLabel.Visible = 'off';
                               app.SoLanLap.Visible = 'off';
                                   app.Gioihan.Visible = 'off';
                                     app.PhanliA.Visible = 'off';
                                         app.Buoch.Visible = 'off';
                                                          app.Sodoanchia.Visible = 'off';
                 app.Sodoanchialb.Visible = 'off';
           app.Buochlb.Visible = 'off';
    app.PhanliB.Visible = 'off';
    app.PTrinh.Value = '[-1 0 1]';
    app.PtrinhLap.Value = '3.^x';
    app.Giatrinoisuy.Value = 0.5;


        end

        % Button pushed function: bai65
        function bai65ButtonPushed(app, event)
                     app.PhuongPhap0.Items = {'Tich Phan Hinh Thang',' '};                   
                    app.SlnlpLabel.Visible = 'off';
                               app.SoLanLap.Visible = 'off';
                                   app.Gioihan.Visible = 'off';
                                     app.PhanliA.Visible = 'on';
                                      app.PhanliB.Visible = 'on';
                                       app.Gioihan.Visible = 'on';
                                        app.GatrcnnisuyLabel.Visible = 'off';
                                app.Giatrinoisuy.Visible = 'off';
                                         app.Ketquadathuc.Visible = 'off';
                                            app.KqNSLb.Visible = 'off';
                                            app.Saiso.Visible = 'on';
                                             app.saisolb.Visible = 'on';
                                             app.NhpdliuLabel.Visible = 'off';
                                             app.PtrinhLap.Visible = 'off';
                                                 app.Buoch.Visible = 'on';
           app.Buochlb.Visible = 'on';
                            app.Sodoanchia.Visible = 'on';
                 app.Sodoanchialb.Visible = 'on';
                                             
                     app.PTrinh.Value =  '(3.5 * x.^2 + 5.1 * x - 4.8) ./ (x - 0.4)'
                         app.PhanliA.Value = 4.2;
                        app.PhanliB.Value = 5.4;
                        app.Buoch.Value = 0.1;
                            app.Saiso.Value = 0.001;
                      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ĐỒ THỊ')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.HVTNBIMINHNHTLPDTV_CLC1MSSV21207070Label = uilabel(app.UIFigure);
            app.HVTNBIMINHNHTLPDTV_CLC1MSSV21207070Label.Text = {'           HỌ VÀ TÊN:      BÙI MINH NHỰT'; '             LỚP:                  DTV_CLC1'; '           MSSV:                   21207070'};
            app.bai32 = uibutton(app.UIFigure, 'push');
            app.bai32.ButtonPushedFcn = createCallbackFcn(app, @bai32ButtonPushed, true);
            app.bai32.Text = 'Bài tập 3.2 (Bài 7)';
            app.bai43 = uibutton(app.UIFigure, 'push');
            app.bai43.ButtonPushedFcn = createCallbackFcn(app, @bai43ButtonPushed, true);
            app.bai43.Text = 'Bài tập 4.4 (Bài 3)';
            app.bai52 = uibutton(app.UIFigure, 'push');
            app.bai52.ButtonPushedFcn = createCallbackFcn(app, @bai52ButtonPushed, true);
            app.bai52.Text = 'Bài tập 5.2';
            app.bai65 = uibutton(app.UIFigure, 'push');
            app.bai65.ButtonPushedFcn = createCallbackFcn(app, @bai65ButtonPushed, true);
            app.bai65.Text = 'Bài tập 6.5 ';
            app.Nghiem = uibutton(app.UIFigure, 'push');
            app.Nghiem.ButtonPushedFcn = createCallbackFcn(app, @NghiemButtonPushed, true);
            app.Nghiem.Text = 'RUN';
            app.PTrinh = uieditfield(app.UIFigure, 'text');
            app.PhanliA = uieditfield(app.UIFigure, 'numeric');
            app.PhanliA.Value = -1;
            app.PhanliB = uieditfield(app.UIFigure, 'numeric');
            app.PhanliB.Value = 2;
            app.Saiso = uieditfield(app.UIFigure, 'numeric');
            app.Saiso.Value = 0.005;
            app.PtrinhLap = uieditfield(app.UIFigure, 'text');
            app.PhuongPhap0 = uidropdown(app.UIFigure);
            app.PhuongPhap0.Items = {'Dây Cung', 'Lặp', 'Newton'};
            app.PhuongPhap0.Value = 'Newton';
            app.SoLanLap = uieditfield(app.UIFigure, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.UIFigure);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.Ketqua = uieditfield(app.UIFigure, 'text');
            app.Gioihan = uilabel(app.UIFigure);
            app.Gioihan.Text = 'Giới hạn';
            app.NhpdliuLabel = uilabel(app.UIFigure);
            app.NhpdliuLabel.Text = 'Nhập dữ liệu';
            app.Giatrinoisuy = uieditfield(app.UIFigure, 'numeric');
            app.GatrcnnisuyLabel = uilabel(app.UIFigure);
            app.GatrcnnisuyLabel.Text = 'Gía trị cần nội suy';
            app.SlnlpLabel = uilabel(app.UIFigure);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.Ketquadathuc = uieditfield(app.UIFigure, 'text');
            app.KqNSLb = uilabel(app.UIFigure);
            app.KqNSLb.Text = 'Kết quả đa thức nội suy';
            app.saisolb = uilabel(app.UIFigure);
            app.saisolb.Text = 'Sai số';
            app.Buoch = uieditfield(app.UIFigure, 'numeric');
            app.Buochlb = uilabel(app.UIFigure);
            app.Buochlb.Text = 'Bước h';
            app.Sodoanchia = uieditfield(app.UIFigure, 'text');
            app.Sodoanchialb = uilabel(app.UIFigure);
            app.Sodoanchialb.Text = 'Số đoạn chia';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo KHTN_REMAKE (1).png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'screenshot_1703951481.jpg';
            app.BOCOGIAKLabel = uilabel(app.UIFigure);
            app.BOCOGIAKLabel.Text = 'BÁO CÁO GIỮA KỲ';
            app.MNPHNGPHPTNHVMATLABLabel = uilabel(app.UIFigure);
            app.MNPHNGPHPTNHVMATLABLabel.Text = 'MÔN: PHƯƠNG PHÁP TÍNH VÀ MATLAB';
            app.PhngtrnhLabel = uilabel(app.UIFigure);
            app.PhngtrnhLabel.Text = 'Phương trình';
            app.PhngphpLabel = uilabel(app.UIFigure);
            app.PhngphpLabel.Text = 'Phương pháp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end